classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceUIFigure             matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        Tab2                          matlab.ui.container.Tab
        updateDrawsCheckBox           matlab.ui.control.CheckBox
        SolvePanel                    matlab.ui.container.Panel
        PerturbationProblemButton     matlab.ui.control.Button
        EigenProblemButton            matlab.ui.control.Button
        EquilibriumProblemButton      matlab.ui.control.Button
        DirectDynamicsProblemButton   matlab.ui.control.Button
        InitialAssemblyProblemButton  matlab.ui.control.Button
        AssemblyProblemButton         matlab.ui.control.Button
        DynamicSolverDropDown         matlab.ui.control.DropDown
        DynamicSolverDropDownLabel    matlab.ui.control.Label
        OnupdateDrawsPanel            matlab.ui.container.Panel
        DirectDynamicProblemCheckBox  matlab.ui.control.CheckBox
        AssemblyProblemCheckBox       matlab.ui.control.CheckBox
    end

    
        properties (Access = public)
        VariableSliders               matlab.ui.control.Slider % Description
        VariableSlidersLabels         matlab.ui.control.Label
    end

    methods (Access = public)

        function results = updateFcn(app)
            global control
            app.OnupdateDrawsPanel.Enable='on';
            if control.update_draws_automatic==true
                app.updateDrawsCheckBox.Value=1;
            else
                app.updateDrawsCheckBox.Value=0;
            end
            if control.assembly_problem_exported==true
                app.AssemblyProblemCheckBox.Enable='on';
                app.AssemblyProblemButton.Enable='on';
            else
                app.AssemblyProblemCheckBox.Enable='off';
                app.AssemblyProblemButton.Enable='off';
            end
            if control.assembly_problem_automatic==true
                app.AssemblyProblemCheckBox.Value=1;
            else
                app.AssemblyProblemCheckBox.Value=0;
            end
            if control.initial_assembly_problem_exported==true
                app.InitialAssemblyProblemButton.Enable='on';
                app.InitialAssemblyProblemButton.Enable='on';
            else
                app.InitialAssemblyProblemButton.Enable='off';
                app.InitialAssemblyProblemButton.Enable='off';
            end
            
            if control.assembly_problem_automatic==true
                app.AssemblyProblemCheckBox.Value=1;
            else
                app.AssemblyProblemCheckBox.Value=0;
            end
            
            if control.direct_dynamics_problem_NE_exported || control.direct_dynamics_problem_VP_exported
                app.DirectDynamicProblemCheckBox.Enable='on';
                app.DirectDynamicsProblemButton.Enable='on';
            else
                app.DirectDynamicProblemCheckBox.Enable='off';
                app.DirectDynamicsProblemButton.Enable='off';
            end
            
            if control.direct_dynamics_problem_automatic==true
                app.DirectDynamicProblemCheckBox.Value=1;
            else
                app.DirectDynamicProblemCheckBox.Value=0;
            end
            
            if control.equilibrium_problem_NE_exported || control.equilibrium_problem_VP_exported
                app.EquilibriumProblemButton.Enable='on';
            else
                app.EquilibriumProblemButton.Enable='off';
            end
            if control.eigen_problem_NE_exported || control.eigen_problem_VP_exported
                app.EigenProblemButton.Enable='on';
            else
                app.EigenProblemButton.Enable='off';
            end
            if control.perturbation_problem_NE_exported || control.perturbation_problem_VP_exported
                app.PerturbationProblemButton.Enable='on';
            else
                app.PerturbationProblemButton.Enable='off';
            end
            
        end
    end
    
    methods (Access = public)
        
        function results = newVariableSlider(app,name,variable_limits)
            n_VariableSliders=length(app.VariableSliders)+1;
            
            app.VariableSlidersLabels(n_VariableSliders) = uilabel(app.InterfaceUIFigure);
            app.VariableSlidersLabels(n_VariableSliders).Interpreter = 'latex';
            app.VariableSlidersLabels(n_VariableSliders).HorizontalAlignment = 'right';           
            app.VariableSlidersLabels(n_VariableSliders).Text = name(2);
            
            if n_VariableSliders==1
            app.VariableSlidersLabels(n_VariableSliders).Position = [300,20,6*(length(app.VariableSlidersLabels(n_VariableSliders).Text)-2),22];
            else
            app.VariableSlidersLabels(n_VariableSliders).Position = app.VariableSlidersLabels(n_VariableSliders-1).Position;
            app.VariableSlidersLabels(n_VariableSliders).Position (2)=app.VariableSlidersLabels(n_VariableSliders).Position (2)+40;
            end
            
            
            app.VariableSliders(n_VariableSliders)=uislider(app.InterfaceUIFigure,'ValueChangedFcn',@SliderValueChanged);
            if n_VariableSliders==1
            app.VariableSliders(n_VariableSliders).Position = [200,30,83,7];
            else
            app.VariableSliders(n_VariableSliders).Position = app.VariableSliders(n_VariableSliders-1).Position;
            app.VariableSliders(n_VariableSliders).Position (2)=app.VariableSliders(n_VariableSliders).Position (2)+40;
            end
            app.VariableSliders(n_VariableSliders).Limits = variable_limits;
            app.VariableSliders(n_VariableSliders).MajorTicks = [variable_limits(1) variable_limits(2)];
            app.VariableSliders(n_VariableSliders).MajorTickLabels = {num2str(variable_limits(1)), num2str(variable_limits(2))};
            app.VariableSliders(n_VariableSliders).MinorTicks = [];
            app.VariableSliders(n_VariableSliders).Value=getValue(name(1));
            app.VariableSliders(n_VariableSliders).UserData=[name];
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateFcn();
        end

        % Value changed function: DynamicSolverDropDown
        function DynamicSolverDropDownValueChanged(app, event)
            global control
            control.dyn_equations_type=app.DynamicSolverDropDown.Value;
        end

        % Callback function
        function DynamicSolverDropDownOpening(app, event)

        end

        % Button pushed function: AssemblyProblemButton
        function AssemblyProblemButtonPushed(app, event)
            assembly_problem;
        end

        % Button pushed function: DirectDynamicsProblemButton
        function DirectDynamicsProblemButtonPushed(app, event)
            direct_dynamics_problem;
        end

        % Button pushed function: InitialAssemblyProblemButton
        function InitialAssemblyProblemButtonPushed(app, event)
            initial_assembly_problem;
        end

        % Button pushed function: PerturbationProblemButton
        function PerturbationProblemButtonPushed(app, event)
            perturbed_dynamic_state_problem;
        end

        % Button pushed function: EigenProblemButton
        function EigenProblemButtonPushed(app, event)
            eigenproblem;
        end

        % Button pushed function: EquilibriumProblemButton
        function EquilibriumProblemButtonPushed(app, event)
            dynamic_equilibrium_problem;
        end

        % Value changed function: updateDrawsCheckBox
        function updateDrawsCheckBoxValueChanged(app, event)
            global control
            value = app.updateDrawsCheckBox.Value;
            control.update_draws_automatic=value;
        end

        % Value changed function: AssemblyProblemCheckBox
        function AssemblyProblemCheckBoxValueChanged(app, event)
            global control
            value = app.AssemblyProblemCheckBox.Value;
            control.assembly_problem_automatic=value;
        end

        % Value changed function: DirectDynamicProblemCheckBox
        function DirectDynamicProblemCheckBoxValueChanged(app, event)
            global control
            value = app.DirectDynamicProblemCheckBox.Value;
            control.direct_dynamics_problem_automatic=value;
        end

        % Close request function: InterfaceUIFigure
        function InterfaceUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceUIFigure = uifigure('Visible', 'off');
            app.InterfaceUIFigure.Name = 'Interface';
            app.InterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @InterfaceUIFigureCloseRequest, true);
            app.OnupdateDrawsPanel = uipanel(app.InterfaceUIFigure);
            app.OnupdateDrawsPanel.Title = 'On updateDraws';
            app.AssemblyProblemCheckBox = uicheckbox(app.OnupdateDrawsPanel);
            app.AssemblyProblemCheckBox.ValueChangedFcn = createCallbackFcn(app, @AssemblyProblemCheckBoxValueChanged, true);
            app.AssemblyProblemCheckBox.Text = 'Assembly Problem';
            app.DirectDynamicProblemCheckBox = uicheckbox(app.OnupdateDrawsPanel);
            app.DirectDynamicProblemCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectDynamicProblemCheckBoxValueChanged, true);
            app.DirectDynamicProblemCheckBox.Text = 'Direct Dynamic Problem';
            app.DynamicSolverDropDownLabel = uilabel(app.InterfaceUIFigure);
            app.DynamicSolverDropDownLabel.Text = 'Dynamic Solver';
            app.DynamicSolverDropDown = uidropdown(app.InterfaceUIFigure);
            app.DynamicSolverDropDown.Items = {'Newton-Euler', 'Virtual_Power'};
            app.DynamicSolverDropDown.ValueChangedFcn = createCallbackFcn(app, @DynamicSolverDropDownValueChanged, true);
            app.DynamicSolverDropDown.Value = 'Newton-Euler';
            app.SolvePanel = uipanel(app.InterfaceUIFigure);
            app.SolvePanel.Title = 'Solve';
            app.AssemblyProblemButton = uibutton(app.SolvePanel, 'push');
            app.AssemblyProblemButton.ButtonPushedFcn = createCallbackFcn(app, @AssemblyProblemButtonPushed, true);
            app.AssemblyProblemButton.Text = 'Assembly Problem';
            app.InitialAssemblyProblemButton = uibutton(app.SolvePanel, 'push');
            app.InitialAssemblyProblemButton.ButtonPushedFcn = createCallbackFcn(app, @InitialAssemblyProblemButtonPushed, true);
            app.InitialAssemblyProblemButton.Text = 'Initial Assembly Problem';
            app.DirectDynamicsProblemButton = uibutton(app.SolvePanel, 'push');
            app.DirectDynamicsProblemButton.ButtonPushedFcn = createCallbackFcn(app, @DirectDynamicsProblemButtonPushed, true);
            app.DirectDynamicsProblemButton.Text = 'Direct Dynamics Problem';
            app.EquilibriumProblemButton = uibutton(app.SolvePanel, 'push');
            app.EquilibriumProblemButton.ButtonPushedFcn = createCallbackFcn(app, @EquilibriumProblemButtonPushed, true);
            app.EquilibriumProblemButton.Text = 'Equilibrium Problem';
            app.EigenProblemButton = uibutton(app.SolvePanel, 'push');
            app.EigenProblemButton.ButtonPushedFcn = createCallbackFcn(app, @EigenProblemButtonPushed, true);
            app.EigenProblemButton.Text = 'Eigen Problem';
            app.PerturbationProblemButton = uibutton(app.SolvePanel, 'push');
            app.PerturbationProblemButton.ButtonPushedFcn = createCallbackFcn(app, @PerturbationProblemButtonPushed, true);
            app.PerturbationProblemButton.Text = 'Perturbation Problem';
            app.updateDrawsCheckBox = uicheckbox(app.InterfaceUIFigure);
            app.updateDrawsCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateDrawsCheckBoxValueChanged, true);
            app.updateDrawsCheckBox.Text = 'updateDraws';
            app.TabGroup = uitabgroup(app.InterfaceUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.SliderLabel = uilabel(app.Tab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Tab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.InterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceUIFigure)
        end
    end
end